classdef Treadmillv03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotTreadmillUIFigure    matlab.ui.Figure
        Plot                      matlab.ui.control.UIAxes
        ConnectionSetupPanel      matlab.ui.container.Panel
        ConnectButton             matlab.ui.control.Button
        DisconnectButton          matlab.ui.control.Button
        SerialPortEditFieldLabel  matlab.ui.control.Label
        SerialPortField           matlab.ui.control.EditField
        Port                      matlab.ui.control.Label
        PortUnav                  matlab.ui.control.Label
        ControlTypePanel          matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        InputType                 matlab.ui.control.Switch
        ManualControlPanel        matlab.ui.container.Panel
        SpeedSliderLabel          matlab.ui.control.Label
        SpeedSlider               matlab.ui.control.Slider
        MPH                       matlab.ui.control.Label
        InputSpeed                matlab.ui.control.NumericEditField
        RunPanel                  matlab.ui.container.Panel
        RunButton                 matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        SpeedProfilePanel         matlab.ui.container.Panel
        ChooseFile                matlab.ui.control.Button
        FileName                  matlab.ui.control.EditField
        LoadProfile               matlab.ui.control.Button
        ErrorPanel                matlab.ui.container.Panel
        CloseButton               matlab.ui.control.Button
        WrongFile                 matlab.ui.control.Label
        HidePlot                  matlab.ui.container.Panel
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset  % Disconnect and delete all instrument objects
            
            % Visible Tabs
            app.PortUnav.Visible = 'off';
            app.ControlTypePanel.Visible = 'off';
            app.ManualControlPanel.Visible = 'off';
            app.RunPanel.Visible = 'off';
            app.SpeedProfilePanel.Visible = 'off';
            
            global stp;
            stp = 1;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 'off';
            global fileExport;
            fileExport = fopen(['expspeed_',datestr(now,'mm_dd_yyyy_HH_MM'),'.csv'],'w');
            global s;
            global stp;
            flushinput(s);
            switch app.InputType.Value
                case 'Manual Control'
                    app.SpeedSlider.Enable = 'on';
                    fwrite(s,'<1,0>');
                    pause(1);
                    table = zeros(1,2);
                    i = 1;
                    stp = 0;
                    while stp == 0
                        app.HidePlot.Visible = 'off';
                        b = s.BytesAvailable;
                    while b > 0
                        tsec = str2double(fscanf(s));
                        sp = str2double(fscanf(s));
                        table(i,1) = tsec;
                        table(i,2) = sp;
                        fprintf(fileExport,'%f,%g\n',tsec,sp);
                        pause(0.5);
                        b = s.BytesAvailable;
                        plot(app.Plot, table(:,1), table(:,2))
                        title(app.Plot, 'Current Speed')
                        i = i+1;
                    end
                    end
                case 'Speed Profile'
                    global profile;
                    maxSpeed = 12;
                    speedInMPH = profile(2,2);
                    if speedInMPH >= maxSpeed;
                        speed = maxSpeed;
                    elseif speedInMPH <= 0;
                        speed = 0;
                    else
                    speed = speedInMPH;
                    end
                    t1 = profile(2,1);              % end of ramp up time
                    t2 = profile(3,1);              % end of flat time
                    t3 = profile(4,1);              % end of ramp down time
                    sendData = sprintf('<2,%d,%d,%d,%d>',speed,t1,t2,t3);
                    fwrite(s,sendData);
                    pause(1);
                    table = zeros(1,2);
                    i = 1;
                    b = s.BytesAvailable;
                    while b > 0 
                        tsec = str2double(fscanf(s));
                        sp = str2double(fscanf(s));
                        table(i,1) = tsec;
                        table(i,2) = sp;
                        fprintf(fileExport,'%f,%g\n',tsec,sp);
                        pause(0.5);
                        b = s.BytesAvailable;
                        plot(app.Plot, table(:,1), table(:,2))
                        title(app.Plot, 'Current Speed')
                        i = i+1;
                    end
                    fclose('all');
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global s;
            global stp;
            stp = 1;
            flushinput(s);
            fclose('all');
            fwrite(s,'<0>');
            fwrite(s,0);
            fwrite(s,'>');
            app.RunButton.Enable = 'on';
            switch app.InputType.Value
                case 'Manual Control'
                    app.SpeedSlider.Enable = 'off';
                    app.SpeedSlider.Value = 0;
                    app.InputSpeed.Value = 0;
                    
                case 'Speed Profile'
                    
            end
        end

        % Value changing function: SpeedSlider
        function SpeedSliderValueChanging(app, event)
            global s;
            flushinput(s); 
            global fileExport;
            speed = event.Value;
            app.InputSpeed.Value = speed;
            sendData = sprintf('<1,%d>',speed);
            fwrite(s,sendData);

        end

        % Value changed function: InputType
        function InputTypeValueChanged(app, event)
            global s;
            flushinput(s);
            fclose('all');
            global stp;
            stp = 1;
            fwrite(s, '<0>');  % If mode is changed, this automatically stops the motor
            
            if app.InputType.Value == "Manual Control"
                app.SpeedSlider.Value = 0;
                app.InputSpeed.Value = 0;
                app.SpeedProfilePanel.Visible = 'off';
                app.ManualControlPanel.Visible = 'on';
                app.SpeedSlider.Enable = 'off';
                app.RunPanel.Visible = 'on';
                app.RunButton.Enable = 'on';
                app.HidePlot.Visible = 'on';
                
            elseif app.InputType.Value == "Speed Profile"
                app.ManualControlPanel.Visible = 'off';
                app.SpeedProfilePanel.Visible = 'on';
                app.ErrorPanel.Visible = 'off';
                app.RunButton.Enable = 'off';
                app.HidePlot.Visible = 'off';
            end
            
        end

        % Button pushed function: ChooseFile
        function ChooseFileButtonPushed(app, event)
            [filename, pathname, filerindex] = uigetfile('.csv');
            filetype = '.csv';
            if filename ~= 0
                if endsWith(filename,filetype) == 0
                    app.ErrorPanel.Visible = 'on';
                else
                    app.FileName.Value = filename;
                    app.LoadProfile.Enable = 'on';
                end
            end
        end

        % Button pushed function: LoadProfile
        function LoadProfileButtonPushed(app, event)
            filename = app.FileName.Value;
            global profile;
            profile = csvread(filename);
            plot(app.Plot, profile(:,1), profile(:,2))
            
            app.RunButton.Enable = 'on';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.ErrorPanel.Visible = 'off';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            instrreset  % Disconnect and delete all instrument objects
            
            global s;
            serialport = app.SerialPortField.Value;
            avports = seriallist;  % Available ports
            
            validport = find(strcmp(avports,serialport));  % Check if input port is available
            
            if size(validport,2) == 0
                app.PortUnav.Visible = 'on';
            else
                % Connect to serial
                s = serial(app.SerialPortField.Value,'BaudRate', 115200);
                fopen(s);
                flushinput(s);
                % Confirm Connection
                app.PortUnav.Visible = 'off';
                app.Port.Visible = 'on';
                
                % Visible Tabs
                app.ControlTypePanel.Visible = 'on';
                app.SpeedProfilePanel.Visible = 'off';
                app.ManualControlPanel.Visible = 'on';
                app.InputType.Value = 'Manual Control';
                app.SpeedSlider.Enable = 'off';
                app.RunPanel.Visible = 'on';
                app.ConnectButton.Enable = 'off';
                
                
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            instrreset  % Disconnect and delete all instrument objects
            
            % Visible Tabs
            app.PortUnav.Visible = 'off';
            app.Port.Visible = 'off';
            app.ControlTypePanel.Visible = 'off';
            app.ManualControlPanel.Visible = 'off';
            app.RunPanel.Visible = 'off';
            app.SpeedProfilePanel.Visible = 'off';
            app.ConnectButton.Enable = 'on';
            app.HidePlot.Visible = 'on';
            
        end

        % Value changed function: InputSpeed
        function InputSpeedValueChanged(app, event)
            speed = app.InputSpeed.Value;
            global fileExport;
            global s;
            flushinput(s);
            app.SpeedSlider.Value = speed;
            sendData = sprintf('<1,%d>',speed);
            fwrite(s,sendData);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotTreadmillUIFigure = uifigure;
            app.RobotTreadmillUIFigure.Name = 'Robot Treadmill';
            app.Plot = uiaxes(app.RobotTreadmillUIFigure);
            title(app.Plot, 'Loaded Profile')
            xlabel(app.Plot, 'Time (seconds)')
            ylabel(app.Plot, 'Velocity (MPH)')
            app.ConnectionSetupPanel = uipanel(app.RobotTreadmillUIFigure);
            app.ConnectionSetupPanel.Title = 'Connection Setup';
            app.ConnectButton = uibutton(app.ConnectionSetupPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionSetupPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.SerialPortEditFieldLabel = uilabel(app.ConnectionSetupPanel);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortField = uieditfield(app.ConnectionSetupPanel, 'text');
            app.SerialPortField.Value = 'COM5';
            app.Port = uilabel(app.ConnectionSetupPanel);
            app.Port.Enable = 'off';
            app.Port.Text = '';
            app.PortUnav = uilabel(app.ConnectionSetupPanel);
            app.PortUnav.Enable = 'off';
            app.PortUnav.Text = 'Port unavailable';
            app.ControlTypePanel = uipanel(app.RobotTreadmillUIFigure);
            app.ControlTypePanel.Title = 'Control Type';
            app.Label = uilabel(app.ControlTypePanel);
            app.Label.Text = '';
            app.InputType = uiswitch(app.ControlTypePanel, 'slider');
            app.InputType.Items = {'Manual Control', 'Speed Profile'};
            app.InputType.ValueChangedFcn = createCallbackFcn(app, @InputTypeValueChanged, true);
            app.InputType.Value = 'Manual Control';
            app.ManualControlPanel = uipanel(app.RobotTreadmillUIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.SpeedSliderLabel = uilabel(app.ManualControlPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.ManualControlPanel);
            app.SpeedSlider.Limits = [0 12];
            app.SpeedSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedSliderValueChanging, true);
            app.MPH = uilabel(app.ManualControlPanel);
            app.MPH.Text = 'MPH';
            app.InputSpeed = uieditfield(app.ManualControlPanel, 'numeric');
            app.InputSpeed.Limits = [0 12];
            app.InputSpeed.ValueDisplayFormat = '%.2f';
            app.InputSpeed.ValueChangedFcn = createCallbackFcn(app, @InputSpeedValueChanged, true);
            app.RunPanel = uipanel(app.RobotTreadmillUIFigure);
            app.RunButton = uibutton(app.RunPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.RunPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SpeedProfilePanel = uipanel(app.RobotTreadmillUIFigure);
            app.SpeedProfilePanel.Title = 'Speed Profile';
            app.ChooseFile = uibutton(app.SpeedProfilePanel, 'push');
            app.ChooseFile.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFile.Text = 'Choose File';
            app.FileName = uieditfield(app.SpeedProfilePanel, 'text');
            app.FileName.Editable = 'off';
            app.LoadProfile = uibutton(app.SpeedProfilePanel, 'push');
            app.LoadProfile.ButtonPushedFcn = createCallbackFcn(app, @LoadProfileButtonPushed, true);
            app.LoadProfile.Enable = 'off';
            app.LoadProfile.Text = 'Load Profile';
            app.ErrorPanel = uipanel(app.SpeedProfilePanel);
            app.ErrorPanel.Title = 'Error';
            app.CloseButton = uibutton(app.ErrorPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WrongFile = uilabel(app.ErrorPanel);
            app.WrongFile.Text = 'Wrong file type. Upload a csv file.';
            app.HidePlot = uipanel(app.RobotTreadmillUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Treadmillv03

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotTreadmillUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotTreadmillUIFigure)
        end
    end
end